classdef robotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InitialiseButton        matlab.ui.control.Button
        TabGroup2               matlab.ui.container.TabGroup
        TeachTab_2              matlab.ui.container.Tab
        LeftPanel               matlab.ui.container.Panel
        ResumeButton            matlab.ui.control.Button
        ESTOPmagicianButton     matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        TeachTab                matlab.ui.container.Tab
        MovetocoordsButton      matlab.ui.control.Button
        ZEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        MoveEndEffectortoLabel  matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel         matlab.ui.control.Label
        Joint5Slider            matlab.ui.control.Slider
        Joint5SliderLabel       matlab.ui.control.Label
        Joint4Slider            matlab.ui.control.Slider
        Joint4SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint1SliderLabel       matlab.ui.control.Label
        MoveTab                 matlab.ui.container.Tab
        YZPlaneButton           matlab.ui.control.Button
        XYPlaneButton           matlab.ui.control.Button
        XZPlaneButton           matlab.ui.control.Button
        SimulationTab           matlab.ui.container.Tab
        StartSimulationButton   matlab.ui.control.Button
        ResumeSimulationButton  matlab.ui.control.Button
        ESTOPsimulationButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % The objects which holds the functions for manipulating the robots
        % and the robot models
        workspace;
    end
    
    methods (Access = private)
        
        function updateSlidersMagician(app,q)
            app.Joint1Slider.Value = q(1);
            app.Joint2Slider.Value = q(2);
            app.Joint3Slider.Value = q(3);
            app.Joint4Slider.Value = q(4);
            app.Joint5Slider.Value = q(5);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            app.InitialiseButton.Visible = false;
            
            app.workspace = Workspace(1);
            app.ESTOPmagicianButton.Visible = true;

            % app.TestestoploopButton.Visible = true;
            
            % creating the slider lengths based on the Joint limits
            qlims = rad2deg(app.workspace.magician.robot.model.qlim);
            q = rad2deg(app.workspace.magician.armQ);
            
            app.Joint1Slider.Limits = qlims(1,:);
            app.Joint2Slider.Limits = qlims(2,:);
            app.Joint3Slider.Limits = qlims(3,:);
            app.Joint4Slider.Limits = qlims(4,:);
            app.Joint5Slider.Limits = qlims(5,:);
            
            updateSlidersMagician(app,q);
            
            app.TabGroup2.Visible = "on";
        end

        % Button pushed function: ESTOPmagicianButton
        function ESTOPmagicianButtonPushed(app, event)
            if app.workspace.magician.eStop
                app.workspace.magician.emergencyStopReset;
                app.ESTOPmagicianButton.Text = "ESTOP (magician)";
                app.ResumeButton.Visible = true;
            else
                app.workspace.magician.emergencyStop;
                app.ESTOPmagicianButton.Text = "Reinitialize";
                app.ResumeButton.Visible = false;
            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.workspace.magician.armQ(1) = changingValue;
            app.workspace.magician.animate;
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.workspace.magician.armQ(2) = changingValue;
            app.workspace.magician.animate;
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.workspace.magician.armQ(3) = changingValue;
            app.workspace.magician.animate;
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.workspace.magician.armQ(4) = changingValue;
            app.workspace.magician.animate;
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = deg2rad(event.Value);
            app.workspace.magician.armQ(5) = changingValue;
            app.workspace.magician.animate;
        end

        % Button pushed function: MovetocoordsButton
        function MovetocoordsButtonPushed(app, event)
            endTr = transl(app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value);
            app.workspace.magician.moveArm(endTr,150);

            q = rad2deg(app.workspace.magician.armQ);
            app.Joint1Slider.Value = q(1);
            app.Joint2Slider.Value = q(2);
            app.Joint3Slider.Value = q(3);
            app.Joint4Slider.Value = q(4);
            app.Joint5Slider.Value = q(5);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            switch app.workspace.magician.resumeFunction
                case 0
                    disp("No section to resume")
                    app.workspace.magician.resume();
                case 1
                    app.workspace.magician.moveArm();
            end
            app.ResumeButton.Visible = false;
        end

        % Button pushed function: XYPlaneButton
        function XYPlaneButtonPushed(app, event)
            view(0,90)
        end

        % Button pushed function: YZPlaneButton
        function YZPlaneButtonPushed(app, event)
            view(90,0)
        end

        % Button pushed function: XZPlaneButton
        function XZPlaneButtonPushed(app, event)
            view(0,0)
        end

        % Button pushed function: ESTOPsimulationButton
        function ESTOPsimulationButtonPushed(app, event)
            if app.workspace.magician.eStop
                % app.workspace.emergencyStopReset;
                % app.workspace.stop = false;
                app.workspace.magician.eStop = false;
                app.ESTOPsimulationButton.Text = "ESTOP (simulation)";
                app.ResumeSimulationButton.Visible = true;
            else
                % app.workspace.emergencyStop;
                % app.workspace.stop = true;
                app.workspace.magician.eStop = true;
                app.ESTOPsimulationButton.Text = "Reinitialize";
                app.ResumeSimulationButton.Visible = false;
            end
        end

        % Button pushed function: ResumeSimulationButton
        function ResumeSimulationButtonPushed(app, event)
            switch app.workspace.magician.resumeFunction % i have no clue why the workspace estop doesn't work, so am using magician...
                case 0
                    disp("No section to resume")
                case 1
                    app.workspace.simulation();
            end
            app.ResumeSimulationButton.Visible = false;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.StartSimulationButton.Visible = false;
            app.workspace.simulation();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Visible = 'off';
            app.TeachTab_2 = uitab(app.TabGroup2);
            app.TeachTab_2.Title = 'Teach';
            app.LeftPanel = uipanel(app.TeachTab_2);
            app.LeftPanel.Title = 'Dobot Magician';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.Joint1SliderLabel = uilabel(app.TeachTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.TeachTab);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.TeachTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.TeachTab);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.TeachTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.TeachTab);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.TeachTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.TeachTab);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.TeachTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.TeachTab);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.XEditFieldLabel = uilabel(app.TeachTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.TeachTab, 'numeric');
            app.MoveEndEffectortoLabel = uilabel(app.TeachTab);
            app.MoveEndEffectortoLabel.Text = 'Move End Effector to:';
            app.YEditFieldLabel = uilabel(app.TeachTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.TeachTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.TeachTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.TeachTab, 'numeric');
            app.MovetocoordsButton = uibutton(app.TeachTab, 'push');
            app.MovetocoordsButton.ButtonPushedFcn = createCallbackFcn(app, @MovetocoordsButtonPushed, true);
            app.MovetocoordsButton.Text = 'Move to coords';
            app.MoveTab = uitab(app.TabGroup);
            app.MoveTab.Title = 'Move';
            app.XZPlaneButton = uibutton(app.MoveTab, 'push');
            app.XZPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @XZPlaneButtonPushed, true);
            app.XZPlaneButton.Text = 'X-Z Plane';
            app.XYPlaneButton = uibutton(app.MoveTab, 'push');
            app.XYPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @XYPlaneButtonPushed, true);
            app.XYPlaneButton.Text = 'X-Y Plane';
            app.YZPlaneButton = uibutton(app.MoveTab, 'push');
            app.YZPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @YZPlaneButtonPushed, true);
            app.YZPlaneButton.Text = 'Y-Z Plane';
            app.ESTOPmagicianButton = uibutton(app.LeftPanel, 'push');
            app.ESTOPmagicianButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPmagicianButtonPushed, true);
            app.ESTOPmagicianButton.Text = 'ESTOP (magician)';
            app.ResumeButton = uibutton(app.LeftPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Visible = 'off';
            app.ResumeButton.Text = 'Resume';
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Title = 'Simulation';
            app.ESTOPsimulationButton = uibutton(app.SimulationTab, 'push');
            app.ESTOPsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPsimulationButtonPushed, true);
            app.ESTOPsimulationButton.Text = 'ESTOP (simulation)';
            app.ResumeSimulationButton = uibutton(app.SimulationTab, 'push');
            app.ResumeSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeSimulationButtonPushed, true);
            app.ResumeSimulationButton.Visible = 'off';
            app.ResumeSimulationButton.Text = 'Resume Simulation';
            app.StartSimulationButton = uibutton(app.SimulationTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end